classdef Simulation_Platform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        Menu_3             matlab.ui.container.Menu
        EnglishMenu        matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        Menu_4             matlab.ui.container.Menu
        Menu_5             matlab.ui.container.Menu
        Menu_6             matlab.ui.container.Menu
        Menu_7             matlab.ui.container.Menu
        EditField_10       matlab.ui.control.NumericEditField
        Panel_4            matlab.ui.container.Panel
        Label_47           matlab.ui.control.Label
        Label_46           matlab.ui.control.Label
        Label_45           matlab.ui.control.Label
        Label_44           matlab.ui.control.Label
        Slider_4           matlab.ui.control.Slider
        Slider_3           matlab.ui.control.Slider
        Slider_2           matlab.ui.control.Slider
        Slider             matlab.ui.control.Slider
        Panel_2            matlab.ui.container.Panel
        Label_33           matlab.ui.control.Label
        Label_32           matlab.ui.control.Label
        Label_31           matlab.ui.control.Label
        Label_30           matlab.ui.control.Label
        Label_29           matlab.ui.control.Label
        Label_28           matlab.ui.control.Label
        Label_27           matlab.ui.control.Label
        Label_26           matlab.ui.control.Label
        Label_25           matlab.ui.control.Label
        Label_24           matlab.ui.control.Label
        msLabel_2          matlab.ui.control.Label
        Label_48           matlab.ui.control.Label
        Label_16           matlab.ui.control.Label
        Label_15           matlab.ui.control.Label
        EditField_9        matlab.ui.control.NumericEditField
        EditField_8        matlab.ui.control.NumericEditField
        EditField_7        matlab.ui.control.NumericEditField
        EditField_6        matlab.ui.control.NumericEditField
        EditField_5        matlab.ui.control.NumericEditField
        EditField_4        matlab.ui.control.NumericEditField
        EditField_3        matlab.ui.control.NumericEditField
        EditField_2        matlab.ui.control.NumericEditField
        EditField_1        matlab.ui.control.NumericEditField
        EditField          matlab.ui.control.NumericEditField
        Label_14           matlab.ui.control.Label
        Label_13           matlab.ui.control.Label
        mLabel             matlab.ui.control.Label
        Label_11           matlab.ui.control.Label
        Label_10           matlab.ui.control.Label
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_12          matlab.ui.control.Button
        Button_10          matlab.ui.control.Button
        Button_9           matlab.ui.control.Button
        Button_8           matlab.ui.control.Button
        Button_7           matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        Button_6           matlab.ui.control.ToggleButton
        Button_5           matlab.ui.control.ToggleButton
        Button_4           matlab.ui.control.ToggleButton
        Label              matlab.ui.control.Label
        Panel_3            matlab.ui.container.Panel
        Label_39           matlab.ui.control.Label
        Label_38           matlab.ui.control.Label
        HeadingIndicator   Aero.ui.control.HeadingIndicator
        RPMIndicator       Aero.ui.control.RPMIndicator
        ClimbIndicator     Aero.ui.control.ClimbIndicator
        AirspeedIndicator  Aero.ui.control.AirspeedIndicator
        Label_34           matlab.ui.control.Label
        Label_37           matlab.ui.control.Label
        ArtificialHorizon  Aero.ui.control.ArtificialHorizon
        Altimeter          Aero.ui.control.Altimeter
        Label_35           matlab.ui.control.Label
        Label_36           matlab.ui.control.Label
    end

    methods (Access = public)
       
        function updata1(app,X)
             global unitype
            x=X;     
            if unitype=='e'
                app.mLabel.Text='ft';
                app.EditField_2.Value=x(14)*3.2808;%h 
%                 app.EditField_9.Value=x(30)*3.2808;%ias
                app.EditField_8.Value=x(36)*3.2808;%风速
                
%                 app.Label_48.Text='ft/s';
                app.msLabel_2.Text='ft/s';

            else
                app.mLabel.Text='m';
                app.EditField_2.Value=x(14);%h 
%                  app.EditField_9.Value=x(30);%ias
                 app.EditField_8.Value=x(36);%风速
                 app.EditField_5.Value=x(38);%状态字
%                 app.Label_48.Text='m/s';
                app.msLabel_2.Text='m/s';
                app.EditField_10.Value=x(1);%时间
                data.time=x(1);
                data.type=x(38);
                setappdata(0,'APP1',data);
            end
            app.EditField.Value=x(12);%long
            app.EditField_1.Value=x(13);%lat
            app.EditField_9.Value=x(32);%马赫数  
            app.EditField_3.Value=x(6);%phi
            app.EditField_4.Value=x(7);%theta
            app.EditField_6.Value=x(5);%alpha
            app.EditField_7.Value=x(29);%beta
            
            
            app.AirspeedIndicator.Airspeed=x(37)*1.9438;%节
            app.ArtificialHorizon.Pitch=x(7);%俯仰
            app.ArtificialHorizon.Roll=x(6);%滚转
            app.Altimeter.Altitude=x(14)*3.2808;%英尺
            app.HeadingIndicator.Heading=x(8);%航向
            app.RPMIndicator.RPM=x(31);%转速
            app.ClimbIndicator.ClimbRate=x(17)*3.2808*19660;%爬升率，英尺/分
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global applanguage unitype
            unitype='m';
            if applanguage=='z'
                Menu_3Selected(app,[]);
            else
                EnglishMenuSelected(app,[]);
            end                        
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            set_param('simModel2020','SimulationCommand','pause');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            set_param('simModel2020','SimulationCommand','stop');
        end

        % Value changed function: Slider_2
        function SliderValueChanged(app, event)
            value = app.Slider_2.Value;
            dE_hand=num2str(value);
            set_param('simModel2020/PlantModel/FlightSwitchModel/Manually piloted aircraft/dE_hand','Value',dE_hand)
        end

        % Value changed function: Slider
        function Slider_2ValueChanged(app, event)
            value = app.Slider.Value;
            dT_hand=num2str(value);
            set_param('simModel2020/PlantModel/FlightSwitchModel/Manually piloted aircraft/dT_hand','Value',dT_hand)
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            dA_hand=num2str(value);
            set_param('simModel2020/PlantModel/FlightSwitchModel/Manually piloted aircraft/dA_hand','Value',dA_hand)
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            dR_hand=num2str(value);
            set_param('simModel2020/PlantModel/FlightSwitchModel/Manually piloted aircraft/dR_hand','Value',dR_hand)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
            global Flag
            if app.Button_4.Value==true
                Flag='0';
                set_param('simModel2020/PlantModel/FlightSwitchModel/SwitchFlightMode','Value',Flag);
               
            elseif app.Button_5.Value==true
                Flag='1';
                set_param('simModel2020/PlantModel/FlightSwitchModel/SwitchFlightMode','Value',Flag);
                
            elseif app.Button_6.Value==true
                Flag='2';
                set_param('simModel2020/PlantModel/FlightSwitchModel/SwitchFlightMode','Value',Flag);
                
            end
            
            %             get_param('simModel2020/PlantModel/FlightSwitchModel/Switch Flight Mode')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            set_param('simModel2020','SimulationCommand','continue');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            set_param('simModel2020','SimulationCommand','step');
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            set_param('simModel2020','SimulationCommand','start');
            delete(app.UIFigure);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            evalin("base",' DataProcess');
            
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            global applanguage
            applanguage='z';
            app.Label.Text='全流程起降飞行仿真平台';
            app.Menu.Text='语言';
            app.Menu_2.Text='单位';
            app.Menu_4.Text='公制单位';
            app.Menu_5.Text='英制单位';
            app.Menu_6.Text='记录';
             app.Menu_7.Text='记录';
            app.ButtonGroup.Title='控制区域';
            app.Button_4.Text='自动驾驶';
            app.Button_2.Text='暂停仿真';
            app.Button_5.Text='半自动驾驶';
            app.Button_6.Text='手动驾驶';
            app.Button_7.Text='继续仿真';
            app.Button_8.Text='步进仿真';
            app.Button_9.Text='开始仿真';
            app.Button_3.Text='结束仿真';
            app.Button_10.Text='数据保存';
            app.Button_12.Text='风场';
            
            app.Panel_4.Title='舵面控制';
            app.Label_44.Text='升降舵';
            app.Label_45.Text='油门';
            app.Label_46.Text='副翼';
            app.Label_47.Text='方向舵';
            
            app.Panel_2.Title='飞行参数';
            app.Label_24.Text='经度';
            app.Label_25.Text='纬度';
            app.Label_26.Text='高度';
            app.Label_27.Text='滚转';
            app.Label_28.Text='俯仰';
            app.Label_29.Text='攻角';
            app.Label_30.Text='侧滑';
            app.Label_31.Text='马赫数';
            app.Label_32.Text='风速';
            app.Label_33.Text='状态';
            
            app.Panel_3.Title='飞行仪表';
            app.Label_34.Text='空速表';
            app.Label_35.Text='高度表';
            app.Label_36.Text='水平仪';
            app.Label_37.Text='升降指示表';
            app.Label_38.Text='航向表';
            app.Label_39.Text='转速表';
            
            
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            global applanguage
            applanguage='e';
            app.Label.Text='Civil Aircraft Simulation Platform';
            app.Menu.Text='Language';
            app.Menu_2.Text='Unit';
            app.Menu_4.Text='Metric units';
            app.Menu_5.Text='English units';
            app.Menu_6.Text='Record';
            app.Menu_7.Text='Record';
            app.ButtonGroup.Title='Platform control';
            app.Button_4.Text='Autonomous';
            app.Button_2.Text='Pause';
            app.Button_5.Text='Semi';
            app.Button_6.Text='Manual';
            app.Button_7.Text='Continue';
            app.Button_8.Text='Step';
            app.Button_9.Text='Run';
            app.Button_3.Text='Stop';
            app.Button_10.Text='Save';
            app.Button_12.Text='Wind';
            app.Panel_4.Title='Control surfaces';
            app.Label_44.Text='Elevator';
            app.Label_45.Text='Throttle';
            app.Label_46.Text='Aileron';
            app.Label_47.Text='Rudder';
            
            app.Panel_2.Title='Flight parameters';
            app.Label_24.Text='Lon';
            app.Label_25.Text='Lat';
            app.Label_26.Text='Height';
            app.Label_27.Text='Roll';
            app.Label_28.Text='Pitch';
            app.Label_29.Text='Attack';
            app.Label_30.Text='Sideslip';
            app.Label_31.Text='Mach';
            app.Label_32.Text='Wind';
            app.Label_33.Text='Flag';
            
            app.Panel_3.Title='Flight instrumentation';
            app.Label_34.Text='Airspeed';
            app.Label_35.Text='Altimeter';
            app.Label_36.Text='Horizon';
            app.Label_37.Text='Climb';
            app.Label_38.Text='Heading';
            app.Label_39.Text='RPM';
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            global unitype
            unitype='e';
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
              global unitype
            unitype='m';
        end

        % Callback function
        function WindSwitchValueChanged(app, event)
%             value = app.WindSwitch.Value;
            if strcmp(app.WindSwitch.Value, 'On')
                  set_param('simModel2020/PlantModel/WindSelect','Value','1');
            elseif strcmp(app.WindSwitch.Value, 'Off')
                set_param('simModel2020/PlantModel/WindSelect','Value','0');
            end
        end

        % Callback function
        function Button_11ValueChanged(app, event)
            set_param('simModel2020/PlantModel/Wind Model/Random wind','Vaule','1');
            
        end

        % Callback function
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
%             get_param('simModel2020/PlantModel/Wind Model/Wind Shear',"MaskNames")
% get_param('simModel2020/PlantModel/Wind Model/Wind Shear',"MaskValues")
set_param('simModel2020/PlantModel/Wind Model/Wind Shear',"Wdeg",value);

        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            windset
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            flytype
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'APP1';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '语言';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '中文';
            app.EnglishMenu = uimenu(app.Menu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '单位';
            app.Menu_4 = uimenu(app.Menu_2);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '公制单位';
            app.Menu_5 = uimenu(app.Menu_2);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '英制单位';
            app.Menu_6 = uimenu(app.UIFigure);
            app.Menu_6.Text = '记录';
            app.Menu_7 = uimenu(app.Menu_6);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '记录';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '飞行仪表';
            app.Label_36 = uilabel(app.Panel_3);
            app.Label_36.Text = '水平仪';
            app.Label_35 = uilabel(app.Panel_3);
            app.Label_35.Text = '高度表';
            app.Altimeter = uiaeroaltimeter(app.Panel_3);
            app.Altimeter.Altitude = 10;
            app.ArtificialHorizon = uiaerohorizon(app.Panel_3);
            app.Label_37 = uilabel(app.Panel_3);
            app.Label_37.Text = '升降指示表';
            app.Label_34 = uilabel(app.Panel_3);
            app.Label_34.Text = '空速表';
            app.AirspeedIndicator = uiaeroairspeed(app.Panel_3);
            app.AirspeedIndicator.Limits = [40 500];
            app.ClimbIndicator = uiaeroclimb(app.Panel_3);
            app.ClimbIndicator.MaximumRate = 4000;
            app.RPMIndicator = uiaerorpm(app.Panel_3);
            app.HeadingIndicator = uiaeroheading(app.Panel_3);
            app.Label_38 = uilabel(app.Panel_3);
            app.Label_38.Text = '航向表';
            app.Label_39 = uilabel(app.Panel_3);
            app.Label_39.Text = '转速表';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '飞行仿真平台';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '控制区域';
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '自动驾驶';
            app.Button_4.Value = true;
            app.Button_5 = uitogglebutton(app.ButtonGroup);
            app.Button_5.Text = '半自动驾驶';
            app.Button_6 = uitogglebutton(app.ButtonGroup);
            app.Button_6.Text = '手动驾驶';
            app.Button_2 = uibutton(app.ButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '暂停仿真';
            app.Button_3 = uibutton(app.ButtonGroup, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '结束仿真';
            app.Button_7 = uibutton(app.ButtonGroup, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '继续仿真';
            app.Button_8 = uibutton(app.ButtonGroup, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '步进仿真';
            app.Button_9 = uibutton(app.ButtonGroup, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '开始仿真';
            app.Button_10 = uibutton(app.ButtonGroup, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '数据保存';
            app.Button_12 = uibutton(app.ButtonGroup, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '风场';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '飞行参数';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '°';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '°';
            app.mLabel = uilabel(app.Panel_2);
            app.mLabel.Text = 'm';
            app.Label_13 = uilabel(app.Panel_2);
            app.Label_13.Text = '°';
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = '°';
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField_1 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_4 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_5 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_9 = uieditfield(app.Panel_2, 'numeric');
            app.Label_15 = uilabel(app.Panel_2);
            app.Label_15.Text = '°';
            app.Label_16 = uilabel(app.Panel_2);
            app.Label_16.Text = '°';
            app.Label_48 = uilabel(app.Panel_2);
            app.Label_48.Text = '';
            app.msLabel_2 = uilabel(app.Panel_2);
            app.msLabel_2.Text = 'm/s';
            app.Label_24 = uilabel(app.Panel_2);
            app.Label_24.Text = '经度';
            app.Label_25 = uilabel(app.Panel_2);
            app.Label_25.Text = '纬度';
            app.Label_26 = uilabel(app.Panel_2);
            app.Label_26.Text = '高度';
            app.Label_27 = uilabel(app.Panel_2);
            app.Label_27.Text = '滚转';
            app.Label_28 = uilabel(app.Panel_2);
            app.Label_28.Text = '俯仰';
            app.Label_29 = uilabel(app.Panel_2);
            app.Label_29.Text = '攻角';
            app.Label_30 = uilabel(app.Panel_2);
            app.Label_30.Text = '侧滑';
            app.Label_31 = uilabel(app.Panel_2);
            app.Label_31.Text = '马赫数';
            app.Label_32 = uilabel(app.Panel_2);
            app.Label_32.Text = '风速';
            app.Label_33 = uilabel(app.Panel_2);
            app.Label_33.Text = '地速';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '舵面控制';
            app.Slider = uislider(app.Panel_4);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2 = uislider(app.Panel_4);
            app.Slider_2.Limits = [-30 30];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_3 = uislider(app.Panel_4);
            app.Slider_3.Limits = [-20 20];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.Panel_4);
            app.Slider_4.Limits = [-3 3];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Label_44 = uilabel(app.Panel_4);
            app.Label_44.Text = '升降舵';
            app.Label_45 = uilabel(app.Panel_4);
            app.Label_45.Text = '油门';
            app.Label_46 = uilabel(app.Panel_4);
            app.Label_46.Text = '副翼';
            app.Label_47 = uilabel(app.Panel_4);
            app.Label_47.Text = '方向舵';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulation_Platform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end