classdef Simulation_Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
        Image     matlab.ui.control.Image
    end

    
    properties (Access = private)
        Property % Description
        filename
        pathname   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             global applanguage
             applanguage='z';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            clearWorkspaceVariables();
            
            [app.filename, app.pathname] = uigetfile('*.xlsx', '选择 Excel 文件');
            if isequal(app.filename,0)
                return;
            end
            
            assignin('base','flywpt',app.filename);
            %   data = readtable(fullfile(app.pathname, app.filename),"VariableNamingRule","preserve");
            %    app.UITable.Data = data;
            %    app.UITable.ColumnName=data.Properties.VariableNames;
            %     app.UITable.Enable = 'on';
            %
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            evalin("base",'Setup')
            
            set_param('simModel2020','SimulationCommand','start');
        end

        % Callback function
        function Button_3Pushed(app, event)
            fullFilePath = fullfile(app.pathname, app.filename);
            modifiedData = app.UITable.Data;
            writetable(modifiedData,fullFilePath);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
           global applanguage
            if applanguage == 'z'
                app.Label.Text=('Civil Aircraft Simulation Platform');
                app.Button.Text=('Profile selection');
                app.Button_2.Text=('Running');
                app.Button_3.Text=('Language');
                applanguage= 'e';
                
            else 
                app.Label.Text=('民用飞机全流程起降仿真平台');
                app.Button.Text=('配置文件选择');
                app.Button_2.Text=('运行平台');
                app.Button_3.Text=('语言切换');
                applanguage= 'z';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'boeing747.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '民用飞机全流程起降仿真平台';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '配置文件选择';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '运行平台';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Tag = '1';
            app.Button_3.Text = '语言切换';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulation_Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end