classdef flytype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global applanguage 
            if applanguage=='z'
                app.Label.Text='飞行状态记录表';
                app.UITable.ColumnName=['时间';'状态'];
                app.Button.Text='记录';
                
            else
                app.Label.Text='Flight Status Record Form';
                app.UITable.ColumnName=['Time';'Tag'];
                app.Button.Text='Record';
            end         
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
           numRows = size(app.UITable.Data, 1);  % 获取当前数据的行数
           app.UITable.Data(numRows+1, :) = {[], []};  % 在表格末尾添加一个空行
           app.UITable.ColumnWidth = {'auto', 'auto'};  % 重新设置列宽以适应新数据
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
         data=getappdata(0,'APP1');
         app.UITable.Data=[app.UITable.Data;{data.time,data.type}];
         app.UITable.ColumnWidth = {'auto', 'auto'};  % 重新设置列宽以适应新数据
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isprop(app, 'UITable') && ~isempty(app.UITable)
            c_data=app.UITable.Data;
            save('UIdata.mat','c_data');
            else 
                disp('Warning: data 不存在，无法保存新数据。');
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'APP2';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'时间'; '状态字'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '记录';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '飞行状态记录表';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flytype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end