classdef windset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        EditField_2  matlab.ui.control.EditField
        EditField_3  matlab.ui.control.EditField
        EditField_4  matlab.ui.control.EditField
        Label_5      matlab.ui.control.Label
        Switch       matlab.ui.control.Switch
        msLabel      matlab.ui.control.Label
        msLabel_2    matlab.ui.control.Label
        msLabel_3    matlab.ui.control.Label
        msLabel_4    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global applanguage 
             if applanguage=='z'
                app.Label_5.Text='风场设置';
                app.Label.Text='定常风';
                app.Label_2.Text='风切变';
                app.Label_3.Text='紊流';
                app.Label_4.Text='随机风';            
            else
                app.Label_5.Text='Wind Set';
                app.Label.Text='Average wind';
                app.Label_2.Text='Wind Shear';
                app.Label_3.Text='Turbulence';
                app.Label_4.Text='Random';
            end                    
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            set_param('simModel2020/PlantModel/Wind Model/Average wind',"Vwind",value)

        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
             set_param('simModel2020/PlantModel/Wind Model/Wind Shear',"W_20",value)

        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
           set_param('simModel2020/PlantModel/Wind Model/Vonkarman Turbulence',"W20",value)

            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            value=[value ' ' value ' ' value];
            value=['[' value ']'];
            set_param('simModel2020/PlantModel/Wind Model/Random wind',"mu",value)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On')
                  set_param('simModel2020/PlantModel/WindSelect','Value','1');
            elseif strcmp(value, 'Off')
                set_param('simModel2020/PlantModel/WindSelect','Value','0');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '定常风';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '风切变';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '紊流';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '随机风';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = '10';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = '10';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = '10';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = '10';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '风场设置';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = 'm/s';
            app.msLabel_3 = uilabel(app.UIFigure);
            app.msLabel_3.Text = 'm/s';
            app.msLabel_4 = uilabel(app.UIFigure);
            app.msLabel_4.Text = 'm/s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = windset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end